classdef gameUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        rock_obstacle        matlab.ui.control.Image
        player               matlab.ui.control.Image
        ScoreEditField       matlab.ui.control.NumericEditField
        ScoreEditFieldLabel  matlab.ui.control.Label
        StartButton          matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function obstacleMovement(app, event)
            obstacleVelocity = 1;
            obstacleCurrPos = app.rock_obstacle.Position;
            constYPos = 100;
            obstacleCurrPos(2) = constYPos;
            offScreenXPos = 0;

            while obstacleCurrPos(1) > offScreenXPos
                obstacleCurrPos(1) = obstacleCurrPos(1) - obstacleVelocity; 
                app.rock_obstacle.Position = obstacleCurrPos;
                drawnow;

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            obstacleMovement(app,event);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            initialY = 100; % Initial Y position
            jumpHeight = 100; % Height to jump
            velocity = 5; % Velocity of jump

            if strcmp(key, 'space') % Check if spacebar is pressed
                % Get the current position of the image
                currentPosition = app.player.Position;
                currentY = currentPosition(2);
                
                % Jump animation
                while currentY < initialY + jumpHeight
                    % Move the image up
                    currentY = currentY + velocity;
                    app.player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end

                % Come back down
                while currentY > initialY
                    % Move the image down
                    currentY = currentY - velocity
                    app.player.Position = [currentPosition(1) currentY currentPosition(3:4)];
                    drawnow; % Update the figure
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ScoreEditFieldLabel = uilabel(app.UIFigure);
            app.ScoreEditFieldLabel.Text = 'Score';
            app.ScoreEditField = uieditfield(app.UIFigure, 'numeric');
            app.player = uiimage(app.UIFigure);
            app.player.ImageSource = fullfile(pathToMLAPP, 'mickey_mouse.png');
            app.rock_obstacle = uiimage(app.UIFigure);
            app.rock_obstacle.ImageSource = fullfile(pathToMLAPP, 'rock_obstacle.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gameUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end